% description: Copyrighted under MPL 2.0. Do not plagurise my code! Made by Zachary Bratten.
classdef AppForArduinoLML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Label              matlab.ui.control.Label
        Image              matlab.ui.control.Image
        ResultField        matlab.ui.control.NumericEditField
        ResultLabel        matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        TakeReadingButton  matlab.ui.control.Button
        TMLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        a; % Later used to construct the Arduino
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.a = arduino(); % Connects the Arduino

             % Changes color of button after connection
             app.TakeReadingA0Button.BackgroundColor = [0.30,0.75,0.93];
        end

        % Button pushed function: TakeReadingButton
        function TakeReadingButtonPushed(app, event)
            % Get value from the digital pin and store it in digital pin
            volts = readVoltage(app.a, "A0");

            R2=10000; % The value of the precision resistor
            temp0 = 298.15; % Reference temperature (25 C), in Kelvin
            res0 = 10000; % Resistance at reference temperature, in Ohms
            B = 3950; % Thermistor B parameter, in Kelvin
            current=volts/R2; % Calculate the current using Ohm's law
            Resistance=(5-volts)/current; % Find the resistance of the thermistor
            recip_temp = 1/temp0 + log(Resistance/res0)/B; % Calculate the reciprocal of the TempK
            TempK = 1./recip_temp; % Calculate Temp in Kelvin
            
            % Type of code switches based on value in dropdown menu
            switch app.DropDown.Value
                case "Voltage" % When voltage is selected...

                    % Tells the output field to use the value of volts
                    app.ResultEditField.Value = volts;

                case "Celsius" % When Celsius is selected... 
                    TempC = TempK -273.15; % Convert to Celsius 

                    % Tells the output field to use the value of TempC
                    app.ResultEditField.Value=TempC; 

                case "Fahrenheit" % When Fahrenheit is selected...
                    TempF = (((TempK - 273.15) * 1.8) + 32); % Convert to Fahrenheit

                    % Tells the output field to use the value of TempF 
                    app.ResultEditField.Value=TempF;
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TMLabel = uilabel(app.GridLayout);
            app.TMLabel.Text = 'Temperature Measurement';
            app.TakeReadingButton = uibutton(app.GridLayout, 'push');
            app.TakeReadingButton.ButtonPushedFcn = createCallbackFcn(app, @TakeReadingButtonPushed, true);
            app.TakeReadingButton.Text = 'Take Reading A0';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Voltage', 'Fahrenheit', 'Celsius'};
            app.DropDown.Value = 'Voltage';
            app.ResultLabel = uilabel(app.GridLayout);
            app.ResultLabel.Text = 'Result';
            app.ResultField = uieditfield(app.GridLayout, 'numeric');
            app.ResultField.Editable = 'off';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'ColdHot.jpg';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Made by'; 'Zachary Bratten'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppForArduinoLML

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end