classdef GUI_POC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Grid                matlab.ui.container.GridLayout
        Lights              matlab.ui.control.StateButton
        ArduinoConnect      matlab.ui.control.Button
        DropdownGrid        matlab.ui.container.GridLayout
        Dropdown            matlab.ui.control.DropDown
        UnitsDropDownLabel  matlab.ui.control.Label
        LoadDataButton      matlab.ui.control.Button
        LightLevel          matlab.ui.control.UIAxes
        Flowrate            matlab.ui.control.UIAxes
        Temperature         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        a;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clear out Arduino
            clear app.a
            % Connect the Arduino
            app.a = arduino();
            %Change Button Color
            app.ArduinoNotConnectedButton.BackgroundColor = [0.30, 0.75, 0.93];
            %Change Button Text
            app.ArduinoNotConnectedButton.Text = "Arduino Connected";
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %Bring Excel Data into App
            data=readtable("ExampleHydroponicData.xlsx");
            %Assign Duration with Values in TimeStamp Column
            Duration = table2array (data(:,"TimeStamp"));
            %Assign TempFah with Values in TempF Column
            TempFah = table2array (data(:,"TempF"));
            %Assign Duration with Values in WaterFlow Column
            Flow = table2array (data(:,"WaterFlow"));
            %Assign Duration with Values in LightIntensity Column
            Light = table2array (data(:,"LightIntensity"));
            
            switch app.UnitsDropDown.Value
                case "Imperial"
                    %Plot TempFah by Duration in app.Temperature
                    plot(app.Temperature,Duration,TempFah);
                    %Modify Ylabel
                    app.Temperature.YLabel.String = "Temperature (F)";
                    %Plot Flow by Duration in app.Temperature
                    plot(app.Flowrate,Duration,Flow);
                    %Modify Ylabel
                    app.Flowrate.YLabel.String = "Water Flow (in/min)";
                    %Plot LightLevel by Duration in app.Temperature
                    plot(app.LightLevel,Duration,Light);
                    
 	            case 'Metric'
		            %Convert Temps to Celsius
                    TempCel=(5/9)*(TempFah-32);
                    %Plot TempCel by Duration in app.Temperature
                    plot(app.Temperature,Duration,TempCel);
                    %Modify Ylabel
                    app.Temperature.YLabel.String = "Temperature (C)";
                    
                    WaterFlowcm=Flow*2.54; %Convert Flow to centimeters
                    %Plot Flow by Duration in app.Flowrate
                    plot(app.Flowrate,Duration,WaterFlowcm);
                    %Modify Ylabel
                    app.Flowrate.YLabel.String = "Water Flow (cm/min)";
 		            
                    %Plot LightLevel by Duration in app.Temperature
                    plot(app.LightLevel,Duration,Light);
            end
        end

        % Value changed function: Lights
        function LightsValueChanged(app, event)
            % Toggle Value when button pressed
            value = app.LightsOffButton.Value; 
            
            if value == 1 % Change Action with Button Press
                % Change Button Text
                app.LightsOffButton.Text = "Lights On"; 
                % Change Button Color
                app.LightsOffButton.BackgroundColor = [1,1,0]; 
                % Turn on Pin 13
                writeDigitalPin (app.a,'D13',1) ; 
         	else 
                % Change Button Text
                app.LightsOffButton.Text = "Lights Off"; 
                % Change Button Color
                app.LightsOffButton.BackgroundColor = [0,.45,.74]; 
                % Turn off Pin 13
 		        writeDigitalPin (app.a,'D13',0) ; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grid = uigridlayout(app.UIFigure);
            app.Temperature = uiaxes(app.Grid);
            title(app.Temperature, 'Recent Temperature Readings')
            xlabel(app.Temperature, 'Date')
            ylabel(app.Temperature, 'Temperature (F)')
            zlabel(app.Temperature, 'Z')
            app.Flowrate = uiaxes(app.Grid);
            title(app.Flowrate, 'Recent Flowrate Readings')
            xlabel(app.Flowrate, 'Date')
            ylabel(app.Flowrate, 'Water Flowrate (in/min)')
            zlabel(app.Flowrate, 'Z')
            app.LightLevel = uiaxes(app.Grid);
            title(app.LightLevel, 'Recent Light Level Readings')
            xlabel(app.LightLevel, 'Date')
            ylabel(app.LightLevel, 'Light Levels in Volts')
            zlabel(app.LightLevel, 'Z')
            app.LoadDataButton = uibutton(app.Grid, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load and Plot Data';
            app.DropdownGrid = uigridlayout(app.Grid);
            app.UnitsDropDownLabel = uilabel(app.DropdownGrid);
            app.UnitsDropDownLabel.Text = 'Units';
            app.Dropdown = uidropdown(app.DropdownGrid);
            app.Dropdown.Items = {'Imperial', 'Metric'};
            app.Dropdown.Value = 'Imperial';
            app.ArduinoConnect = uibutton(app.Grid, 'push');
            app.ArduinoConnect.Text = 'Arduino Not Connected';
            app.Lights = uibutton(app.Grid, 'state');
            app.Lights.ValueChangedFcn = createCallbackFcn(app, @LightsValueChanged, true);
            app.Lights.Text = 'Lights Off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_POC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end