classdef ServoTestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StopButton   matlab.ui.control.Button
        EditField    matlab.ui.control.NumericEditField
        StartButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        stopRequested = false;
        a;
        s1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a=arduino();
            app.s1 = servo(app.a, 'D3', 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);
            writePosition(app.s1, 0.30);
            app.EditField.Value = 0.30;
        end

        % Button pushed function: StartButton
        function WhenPushed(app, event)
            writePosition(app.s1, app.EditField.Value);
            %{
            while (~app.stopRequested)
                writePosition(app.s1, app.EditField.Value);
                pause(1.000);
            end
            %}
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopRequested = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @WhenPushed, true);
            app.StartButton.Text = 'Start';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.Value = 0.3;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ServoTestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end